classdef heatit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HeatitUIFigure              matlab.ui.Figure
        GridLayout2                 matlab.ui.container.GridLayout
        StartButton                 matlab.ui.control.Button
        SettingPanel                matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        DatasetDropDownLabel        matlab.ui.control.Label
        DatasetDropDown             matlab.ui.control.DropDown
        NumberOfPointDropDownLabel  matlab.ui.control.Label
        NumberOfPointDropDown       matlab.ui.control.DropDown
        CodelengthDropDownLabel     matlab.ui.control.Label
        CodelengthDropDown          matlab.ui.control.DropDown
        MethodLabel                 matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        AGH1CheckBox                matlab.ui.control.CheckBox
        AGH2CheckBox                matlab.ui.control.CheckBox
        BRECheckBox                 matlab.ui.control.CheckBox
        CHCheckBox                  matlab.ui.control.CheckBox
        CPHCheckBox                 matlab.ui.control.CheckBox
        DSHCheckBox                 matlab.ui.control.CheckBox
        IsoHCheckBox                matlab.ui.control.CheckBox
        ITQCheckBox                 matlab.ui.control.CheckBox
        KLSHCheckBox                matlab.ui.control.CheckBox
        LSHCheckBox                 matlab.ui.control.CheckBox
        SHCheckBox                  matlab.ui.control.CheckBox
        SpHCheckBox                 matlab.ui.control.CheckBox
        USPLHCheckBox               matlab.ui.control.CheckBox
        OutputPanel                 matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        OutputTextArea              matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function [] = calc(app, dataset, methodCandi, numberOfPoint, codelength)
            
            addpath(genpath('./'))
            
            % read trainset, testset and groundtruthset
            trainset = double(fvecs_read(['./dataset/', dataset, '/', dataset, '_base.fvecs']));
            trainset = trainset';
            testset = fvecs_read(['./dataset/', dataset, '/', dataset, '_query.fvecs']);
            testset = testset';
            groundtruthset = ivecs_read(['./dataset/', dataset, '/', dataset, '_groundtruth.ivecs']);
            groundtruthset = groundtruthset';
            
            % create tiled chart layout, introduced in R2019b
            t = tiledlayout(1, 3);
            title(t, 'Please wait, do not close this window');
            
            for m = 1:length(methodCandi)
                method = methodCandi{m};
                the_number_of_retrieved_samples_vector = round(logspace(0, log10(length(trainset)), numberOfPoint));
                % the_number_of_retrieved_samples_vector = (0:1000:length(trainset)); % debug
                
                displayText(app, '==============================');
                displayText(app, [num2str(codelength), '-bits ', method, ' on ', dataset]);
                displayText(app, '------------------------------');
                
                % start training and testing
                trainStr = ['[model, trainB, train_elapse] = ', method, '_learn(trainset, codelength);'];
                testStr = ['[testB, test_elapse] = ', method, '_compress(testset, model);'];
                eval(trainStr);
                eval(testStr);
                
                ntrain = size(trainB, 1);
                ntest = size(testB, 1);
                
                realcodelength = size(trainB, 2);
                displayText(app, ['Total training time: ', num2str(train_elapse)]);
                displayText(app, ['Total test time: ', num2str(test_elapse)]);
                
                % used to draw curves
                recall_vector = Inf(1, length(the_number_of_retrieved_samples_vector));
                precision_vector = Inf(1, length(the_number_of_retrieved_samples_vector));
                
                % start searching
                for n = 1:length(the_number_of_retrieved_samples_vector)
                    number_retrieved = the_number_of_retrieved_samples_vector(n);
                    
                    recall_sum = 0; % used to calculate average recall
                    precision_sum = 0; % used to calculate average precision
                    
                    for ii = 1:size(testB, 1)
                        
                        res = Inf(length(trainB), 2);
                        
                        for jj = 1:size(trainB, 1)
                            res(jj, 1) = jj;
                            res(jj, 2) = hammingDist(testB(ii, :), trainB(jj, :));
                        end
                        
                        res = sortrows(res, 2); % sort by hamming distance
                        res = res(1:number_retrieved, :);
                        
                        % groundtruthset data is numbered from 0
                        recall = length(intersect(res(:, 1), groundtruthset(ii, :)' + 1)) / length(groundtruthset);
                        recall_sum = recall_sum + recall;
                        precision = length(intersect(res(:, 1), groundtruthset(ii, :)' + 1)) / number_retrieved;
                        precision_sum = precision_sum + precision;
                    end
                    
                    average_recall = recall_sum / length(groundtruthset);
                    displayText(app, ['Average recall: ', num2str(average_recall)]);
                    recall_vector(n) = average_recall;
                    
                    average_precision = precision_sum / length(groundtruthset);
                    displayText(app, ['Average precision: ', num2str(average_precision)]);
                    precision_vector(n) = average_precision;
                    
                    % 68-95-99.7 rule
                    % if average_recall >= 0.997
                    if average_recall >= 1
                        displayText(app, ['break, number_retrieved is ', num2str(number_retrieved)]);
                        break
                    end
                    
                end
                
                ax1 = nexttile(1);
                plot(ax1, the_number_of_retrieved_samples_vector, recall_vector, '-*');
                hold(ax1, 'on');
                
                ax2 = nexttile(2);
                plot(ax2, the_number_of_retrieved_samples_vector, precision_vector, '-*');
                hold(ax2, 'on');
                
                ax3 = nexttile(3);
                plot(ax3, recall_vector, precision_vector, '-*');
                hold(ax3, 'on');
            end
            
            displayText(app, '==============================');
            displayText(app, 'Completed');
            
            % add title and axis labels to chart
            
            title(t, [num2str(codelength), '-bits code length on ', dataset, ' dataset']);
            
            legend(ax1, methodCandi, 'Location', 'southeast');
            title(ax1, 'Recall - the number of retrieved samples curves');
            xlabel(ax1, 'The number of retrieved samples');
            ylabel(ax1, 'Recall');
            hold(ax1, 'off');
            
            legend(ax2, methodCandi, 'Location', 'northeast');
            title(ax2, 'Precision - the number of retrieved samples curves');
            xlabel(ax2, 'The number of retrieved samples');
            ylabel(ax2, 'Precision');
            hold(ax2, 'off');
            
            legend(ax3, methodCandi, 'Location', 'northeast');
            title(ax3, 'Precision - recall curves');
            xlabel(ax3, 'Recall');
            ylabel(ax3, 'Precision');
            hold(ax3, 'off');
        end
        
        function [] = displayText(app, string)
            currString = app.OutputTextArea.Value;
            currString{end + 1} = string;
            app.OutputTextArea.Value = currString;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = false;
            
            dataset = app.DatasetDropDown.Value;
            methodCandi = {};
            if app.AGH1CheckBox.Value == true
                methodCandi{end + 1} = 'AGH1';
            end
            if app.AGH2CheckBox.Value == true
                methodCandi{end + 1} = 'AGH2';
            end
            if app.BRECheckBox.Value == true
                methodCandi{end + 1} = 'BRE';
            end
            if app.CHCheckBox.Value == true
                methodCandi{end + 1} = 'CH';
            end
            if app.CPHCheckBox.Value == true
                methodCandi{end + 1} = 'CPH';
            end
            if app.DSHCheckBox.Value == true
                methodCandi{end + 1} = 'DSH';
            end
            if app.IsoHCheckBox.Value == true
                methodCandi{end + 1} = 'IsoH';
            end
            if app.ITQCheckBox.Value == true
                methodCandi{end + 1} = 'ITQ';
            end
            if app.KLSHCheckBox.Value == true
                methodCandi{end + 1} = 'KLSH';
            end
            if app.LSHCheckBox.Value == true
                methodCandi{end + 1} = 'LSH';
            end
            if app.SHCheckBox.Value == true
                methodCandi{end + 1} = 'SH';
            end
            if app.SpHCheckBox.Value == true
                methodCandi{end + 1} = 'SpH';
            end
            if app.USPLHCheckBox.Value == true
                methodCandi{end + 1} = 'USPLH';
            end
            numberOfPoint = str2double(app.NumberOfPointDropDown.Value);
            codelength = str2double(app.CodelengthDropDown.Value);
            
            app.OutputTextArea.Value = 'Starting...';
            displayText(app, '==============================');
            displayText(app, ['Dataset: ', dataset]);
            displayText(app, ['Method: ', strjoin(methodCandi)]);
            displayText(app, ['NumberOfPoint: ', num2str(numberOfPoint)]);
            displayText(app, ['Codelength: ', num2str(codelength)]);
            calc(app, dataset, methodCandi, numberOfPoint, codelength);
            
            app.StartButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HeatitUIFigure = uifigure('Visible', 'off');
            app.HeatitUIFigure.Name = 'HEATIT';
            app.GridLayout2 = uigridlayout(app.HeatitUIFigure);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SettingPanel = uipanel(app.GridLayout2);
            app.SettingPanel.Title = 'Setting';
            app.GridLayout = uigridlayout(app.SettingPanel);
            app.DatasetDropDownLabel = uilabel(app.GridLayout);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.GridLayout);
            app.DatasetDropDown.Items = {'siftsmall', 'sift', 'gist'};
            app.DatasetDropDown.Value = 'siftsmall';
            app.NumberOfPointDropDownLabel = uilabel(app.GridLayout);
            app.NumberOfPointDropDownLabel.Text = 'NumberOfPoint';
            app.NumberOfPointDropDown = uidropdown(app.GridLayout);
            app.NumberOfPointDropDown.Items = {'15', '20', '25', '30', '35', '40', '45', '50'};
            app.NumberOfPointDropDown.Value = '15';
            app.CodelengthDropDownLabel = uilabel(app.GridLayout);
            app.CodelengthDropDownLabel.Text = 'Codelength';
            app.CodelengthDropDown = uidropdown(app.GridLayout);
            app.CodelengthDropDown.Items = {'16', '32', '64', '128', '256', '512', '1024'};
            app.CodelengthDropDown.Value = '16';
            app.MethodLabel = uilabel(app.GridLayout);
            app.MethodLabel.Text = 'Method';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.AGH1CheckBox = uicheckbox(app.GridLayout3);
            app.AGH1CheckBox.Text = 'AGH1';
            app.AGH2CheckBox = uicheckbox(app.GridLayout3);
            app.AGH2CheckBox.Text = 'AGH2';
            app.BRECheckBox = uicheckbox(app.GridLayout3);
            app.BRECheckBox.Text = 'BRE';
            app.CHCheckBox = uicheckbox(app.GridLayout3);
            app.CHCheckBox.Text = 'CH';
            app.CPHCheckBox = uicheckbox(app.GridLayout3);
            app.CPHCheckBox.Text = 'CPH';
            app.DSHCheckBox = uicheckbox(app.GridLayout3);
            app.DSHCheckBox.Text = 'DSH';
            app.IsoHCheckBox = uicheckbox(app.GridLayout3);
            app.IsoHCheckBox.Text = 'IsoH';
            app.ITQCheckBox = uicheckbox(app.GridLayout3);
            app.ITQCheckBox.Text = 'ITQ';
            app.KLSHCheckBox = uicheckbox(app.GridLayout3);
            app.KLSHCheckBox.Text = 'KLSH';
            app.LSHCheckBox = uicheckbox(app.GridLayout3);
            app.LSHCheckBox.Text = 'LSH';
            app.SHCheckBox = uicheckbox(app.GridLayout3);
            app.SHCheckBox.Text = 'SH';
            app.SpHCheckBox = uicheckbox(app.GridLayout3);
            app.SpHCheckBox.Text = 'SpH';
            app.USPLHCheckBox = uicheckbox(app.GridLayout3);
            app.USPLHCheckBox.Text = 'USPLH';
            app.OutputPanel = uipanel(app.GridLayout2);
            app.OutputPanel.Title = 'Output';
            app.GridLayout4 = uigridlayout(app.OutputPanel);
            app.OutputTextArea = uitextarea(app.GridLayout4);
            % Show the figure after all components are created
            app.HeatitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = heatit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HeatitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HeatitUIFigure)
        end
    end
end